classdef prototype1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ArmDisarmButton             matlab.ui.control.StateButton
        StateLampLabel              matlab.ui.control.Label
        StateLamp                   matlab.ui.control.Lamp
        ModeLabel                   matlab.ui.control.Label
        Mode                        matlab.ui.control.ToggleSwitch
        Aux1SwitchLabel             matlab.ui.control.Label
        Aux1Switch                  matlab.ui.control.ToggleSwitch
        AirspeedIndicatorLabel      matlab.ui.control.Label
        AirspeedIndicator           Aero.ui.control.AirspeedIndicator
        ArtificialHorizonLabel      matlab.ui.control.Label
        ArtificialHorizon           Aero.ui.control.ArtificialHorizon
        HeadingIndicatorLabel       matlab.ui.control.Label
        HeadingIndicator            Aero.ui.control.HeadingIndicator
        AltimeterLabel              matlab.ui.control.Label
        Altimeter                   Aero.ui.control.Altimeter
        PortDropDownLabel           matlab.ui.control.Label
        PortDropDown                matlab.ui.control.DropDown
        Aux2SwitchLabel             matlab.ui.control.Label
        Aux2Switch                  matlab.ui.control.Switch
        Aux3SwitchLabel             matlab.ui.control.Label
        Aux3Switch                  matlab.ui.control.Switch
        BudrateDropDownLabel        matlab.ui.control.Label
        BudrateDropDown             matlab.ui.control.DropDown
        ConnectButton               matlab.ui.control.StateButton
        MapPanel                    matlab.ui.container.Panel
        Aux4SwitchLabel             matlab.ui.control.Label
        Aux4Switch                  matlab.ui.control.Switch
        HTML                        matlab.ui.control.HTML
        Image                       matlab.ui.control.Image
        BatteryVoltageGaugeLabel    matlab.ui.control.Label
        BatteryVoltageGauge         matlab.ui.control.Gauge
        JoystickPortEditFieldLabel  matlab.ui.control.Label
        JoystickPortEditField       matlab.ui.control.NumericEditField
        B1LampLabel                 matlab.ui.control.Label
        B1Lamp                      matlab.ui.control.Lamp
        B2LampLabel                 matlab.ui.control.Label
        B2Lamp                      matlab.ui.control.Lamp
        B3LampLabel                 matlab.ui.control.Label
        B3Lamp                      matlab.ui.control.Lamp
        B4LampLabel                 matlab.ui.control.Label
        B4Lamp                      matlab.ui.control.Lamp
        LU1LampLabel                matlab.ui.control.Label
        LU1Lamp                     matlab.ui.control.Lamp
        RU1LampLabel                matlab.ui.control.Label
        RU1Lamp                     matlab.ui.control.Lamp
        LB2LampLabel                matlab.ui.control.Label
        LB2Lamp                     matlab.ui.control.Lamp
        RB2LampLabel                matlab.ui.control.Label
        RB2Lamp                     matlab.ui.control.Lamp
        SelectLampLabel             matlab.ui.control.Label
        SelectLamp                  matlab.ui.control.Lamp
        StartLamp_2Label            matlab.ui.control.Label
        StartLamp_2                 matlab.ui.control.Lamp
        SWLLampLabel                matlab.ui.control.Label
        SWLLamp                     matlab.ui.control.Lamp
        SWRLampLabel                matlab.ui.control.Label
        SWRLamp                     matlab.ui.control.Lamp
    end

    
      properties (Access = private)
          
          serialPort 
          joystick
        
      end


        methods (Access = private)
            
        
            function updateGauges(app,~)
            
            while true
                
            
            % Read data from serial port
            data = readline(app.serialPort);
            values = strsplit(data, ',');


            for i = 1:length(values)
                
            value = str2double(values{i}); % Example altitude value
         
            % Process data (parse, convert, etc.)
             
            if i == 1
            app.AirspeedIndicator.Value = value;
            end
            
            if i == 2
            app.Altimeter.Value = value;
            end
             
            if i == 3
            app.BatteryVoltageGauge.Value = value;
            end
             
            if i == 4
            app.ArtificialHorizon.Pitch = value;
            end
              
            if i == 5
            app.ArtificialHorizon.Roll = value;
            end
            
            if i == 6
            app.HeadingIndicator.Heading = value;
            end
            
            if i == 8
            gx = geoaxes(app.MapPanel);   
            geoplot(gx, str2double(values{i - 1}),str2double(values{i}), 'rv'); % Example marker plot
            geolimits(gx, [-90 90], [-180 180]); % Set map limits
            geobasemap(gx, 'streets'); % Set map style
                
            end
            
            end


                x = axis(app.joystick,1);
                y = axis(app.joystick,2);
                z = axis(app.joystick,3);
                w = axis(app.joystick,4);
                
                Pov = pov(app.joystick,1);
               
                b1 = button(app.joystick,1);
                b2 = button(app.joystick,2);
                b3 = button(app.joystick,3);
                b4 = button(app.joystick,4);
                b5 = button(app.joystick,5);
                b6 = button(app.joystick,6);
                b7 = button(app.joystick,7);
                b8 = button(app.joystick,8);
                b9 = button(app.joystick,9);
                b10 = button(app.joystick,10);
                b11 = button(app.joystick,11);
                b12 = button(app.joystick,12);
                
                
                try
                    
                % Format data as a string
                
                
                data = sprintf('X: %f, Y: %f, Z: %f, W: %f, Pov: %d, B1: %d, B2: %d, B3: %d, B4: %d, B5: %d, B6: %d, B7: %d, B8: %d, B9: %d, B10: %d, B11: %d, B12: %d', ...
                               x, y, z, w, Pov, b1, b2, b3, b4, b5, b6, b7, b8, b9, b10, b11, b12);
                           
                           
                % Write data to the serial port
                writeline(app.serialPort, data);
                
              
                catch exception
                    
                disp('Error occurred while communicating with serial port:');
                disp(exception.message);
                
                end
                
                if(b1 == 1)
                    
                    app.B1Lamp.Color = 'red';
                    
                elseif(b2 == 1)
                    
                    app.B2Lamp.Color = 'red'; 
                    
                elseif(b3 == 1)
                    
                    app.B3Lamp.Color = 'red'; 
                    
                elseif(b4 == 1)
                    
                    app.B4Lamp.Color = 'red'; 
                
                elseif(b5 == 1)
                    
                    app.LU1Lamp.Color = 'red'; 
                    
                elseif(b6 == 1)
                    
                    app.RU1Lamp.Color = 'red'; 
                    
                elseif(b7 == 1)
                    
                    app.LB2Lamp.Color = 'red'; 
                    
                elseif(b8 == 1)
                    
                    app.RB2Lamp.Color = 'red'; 
                    
                elseif(b9 == 1)
                    
                    app.SelectLamp.Color = 'red'; 
                    
                elseif(b10 == 1)
                    
                    app.StartLamp_2.Color = 'red'; 
                    
                elseif(b11 == 1)
                    
                    app.SWLLamp.Color = 'red'; 
                    
                elseif(b12 == 1)
                    
                    app.SWRLamp.Color = 'red';
                    
                else
                    app.B1Lamp.Color = 'green';
                    app.B2Lamp.Color = 'green';
                    app.B3Lamp.Color = 'green';
                    app.B4Lamp.Color = 'green';
                    
                    app.LU1Lamp.Color = 'green';
                    app.RU1Lamp.Color = 'green';
                    app.LB2Lamp.Color = 'green';
                    app.RB2Lamp.Color = 'green';
                    
                    app.SelectLamp.Color = 'green';
                    app.StartLamp_2.Color = 'green';
                    app.SWRLamp.Color = 'green';
                    app.SWLLamp.Color = 'green';
                    
                end
                
                fprintf('x = %f, y = %f,z = %f, w = %f\n',x,y,z,w);
                fprintf('Pov = %f\n',Pov);


                pause(0.035);
                
              
            end
          end
        
            
        function openSerialPort(app)
            
            comPort = app.PortDropDown.Value;
            baudRate =  str2double(app.BudrateDropDown.Value);
            app.serialPort = serialport(comPort,baudRate);
            
            id = app.JoystickPortEditField.Value;
            app.joystick = vrjoystick(id);  
            
        end
       end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ArmDisarmButton
        function ArmDisarmButtonValueChanged(app, event)
            value0 = app.ArmDisarmButton.Value;
            
            if value0 == 1
                
            app.StateLamp.Color = 'green';
            gx = geoaxes(app.MapPanel);   
            geoplot(gx, 24.16, 72.42, 'rv'); % Example marker plot
            geolimits(gx, [-90 90], [-180 180]); % Set map limits
            geobasemap(gx, 'streets'); % Set map style
                
                
            end
            
            if value0 == 0
            app.StateLamp.Color = 'red';
            end
            
        end

        % Value changed function: Aux2Switch
        function Aux2SwitchValueChanged(app, event)
            value1 = app.Aux2Switch.Value;
            
        end

        % Value changed function: Aux3Switch
        function Aux3SwitchValueChanged(app, event)
            value2 = app.Aux3Switch.Value;
            
        end

        % Callback function
        function Aux4SwitchValueChanged(app, event)
            value3 = app.Aux4Switch.Value;
            
        end

        % Value changed function: Aux1Switch
        function Aux1SwitchValueChanged(app, event)
            value4 = app.Aux1Switch.Value;
            
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value5 = app.ConnectButton.Value;
            
            if(value5 == 1)
                
                app.ConnectButton.Text = 'Disconnect';
                openSerialPort(app);
                updateGauges(app);


            else
                
                app.ConnectButton.Text = 'Connect';
                delete(app.serialPort);
                
         
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArmDisarmButton = uibutton(app.UIFigure, 'state');
            app.ArmDisarmButton.ValueChangedFcn = createCallbackFcn(app, @ArmDisarmButtonValueChanged, true);
            app.ArmDisarmButton.Text = {'Arm/Disarm'; ''; ''};
            app.StateLampLabel = uilabel(app.UIFigure);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.UIFigure);
            app.StateLamp.Tag = 'indecator';
            app.ModeLabel = uilabel(app.UIFigure);
            app.ModeLabel.Text = 'Mode';
            app.Mode = uiswitch(app.UIFigure, 'toggle');
            app.Mode.Items = {'Auto', 'Manual'};
            app.Mode.Value = 'Auto';
            app.Aux1SwitchLabel = uilabel(app.UIFigure);
            app.Aux1SwitchLabel.Text = 'Aux 1';
            app.Aux1Switch = uiswitch(app.UIFigure, 'toggle');
            app.Aux1Switch.Items = {'High', 'Low'};
            app.Aux1Switch.ValueChangedFcn = createCallbackFcn(app, @Aux1SwitchValueChanged, true);
            app.Aux1Switch.Value = 'High';
            app.AirspeedIndicatorLabel = uilabel(app.UIFigure);
            app.AirspeedIndicatorLabel.Text = 'Airspeed Indicator';
            app.AirspeedIndicator = uiaeroairspeed(app.UIFigure);
            app.ArtificialHorizonLabel = uilabel(app.UIFigure);
            app.ArtificialHorizonLabel.Text = 'ArtificialHorizon';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.HeadingIndicatorLabel = uilabel(app.UIFigure);
            app.HeadingIndicatorLabel.Text = 'HeadingIndicator';
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.AltimeterLabel = uilabel(app.UIFigure);
            app.AltimeterLabel.Text = 'Altimeter';
            app.Altimeter = uiaeroaltimeter(app.UIFigure);
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', ''};
            app.PortDropDown.Value = 'COM1';
            app.Aux2SwitchLabel = uilabel(app.UIFigure);
            app.Aux2SwitchLabel.Text = 'Aux 2';
            app.Aux2Switch = uiswitch(app.UIFigure, 'slider');
            app.Aux2Switch.ValueChangedFcn = createCallbackFcn(app, @Aux2SwitchValueChanged, true);
            app.Aux3SwitchLabel = uilabel(app.UIFigure);
            app.Aux3SwitchLabel.Text = 'Aux 3';
            app.Aux3Switch = uiswitch(app.UIFigure, 'slider');
            app.Aux3Switch.ValueChangedFcn = createCallbackFcn(app, @Aux3SwitchValueChanged, true);
            app.BudrateDropDownLabel = uilabel(app.UIFigure);
            app.BudrateDropDownLabel.Text = 'Budrate';
            app.BudrateDropDown = uidropdown(app.UIFigure);
            app.BudrateDropDown.Items = {'9600', '57600', '115200'};
            app.BudrateDropDown.ItemsData = {'9600', '57600', '115200', ''};
            app.BudrateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.Aux4SwitchLabel = uilabel(app.UIFigure);
            app.Aux4SwitchLabel.Text = {'Aux4'; ''};
            app.Aux4Switch = uiswitch(app.UIFigure, 'slider');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'visual.html';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'icon.jpg';
            app.BatteryVoltageGaugeLabel = uilabel(app.UIFigure);
            app.BatteryVoltageGaugeLabel.Text = {'Battery Voltage'; ''};
            app.BatteryVoltageGauge = uigauge(app.UIFigure, 'circular');
            app.BatteryVoltageGauge.Limits = [0 16];
            app.BatteryVoltageGauge.MajorTicks = [0 2 4 6 8 10 12.5 14.5];
            app.JoystickPortEditFieldLabel = uilabel(app.UIFigure);
            app.JoystickPortEditFieldLabel.Text = {'Joystick Port'; ''; ''};
            app.JoystickPortEditField = uieditfield(app.UIFigure, 'numeric');
            app.JoystickPortEditField.Value = 1;
            app.B1LampLabel = uilabel(app.UIFigure);
            app.B1LampLabel.Text = {'B1'; ''};
            app.B1Lamp = uilamp(app.UIFigure);
            app.B2LampLabel = uilabel(app.UIFigure);
            app.B2LampLabel.Text = {'B2'; ''};
            app.B2Lamp = uilamp(app.UIFigure);
            app.B3LampLabel = uilabel(app.UIFigure);
            app.B3LampLabel.Text = {'B3'; ''};
            app.B3Lamp = uilamp(app.UIFigure);
            app.B4LampLabel = uilabel(app.UIFigure);
            app.B4LampLabel.Text = {'B4'; ''};
            app.B4Lamp = uilamp(app.UIFigure);
            app.LU1LampLabel = uilabel(app.UIFigure);
            app.LU1LampLabel.Text = {'LU1'; ''; ''};
            app.LU1Lamp = uilamp(app.UIFigure);
            app.RU1LampLabel = uilabel(app.UIFigure);
            app.RU1LampLabel.Text = {'RU1'; ''; ''};
            app.RU1Lamp = uilamp(app.UIFigure);
            app.LB2LampLabel = uilabel(app.UIFigure);
            app.LB2LampLabel.Text = {'LB2'; ''; ''};
            app.LB2Lamp = uilamp(app.UIFigure);
            app.RB2LampLabel = uilabel(app.UIFigure);
            app.RB2LampLabel.Text = {'RB2'; ''; ''; ''};
            app.RB2Lamp = uilamp(app.UIFigure);
            app.SelectLampLabel = uilabel(app.UIFigure);
            app.SelectLampLabel.Text = {'Select'; ''; ''; ''};
            app.SelectLamp = uilamp(app.UIFigure);
            app.StartLamp_2Label = uilabel(app.UIFigure);
            app.StartLamp_2Label.Text = {'Start'; ''; ''};
            app.StartLamp_2 = uilamp(app.UIFigure);
            app.SWLLampLabel = uilabel(app.UIFigure);
            app.SWLLampLabel.Text = {'SWL'; ''};
            app.SWLLamp = uilamp(app.UIFigure);
            app.SWRLampLabel = uilabel(app.UIFigure);
            app.SWRLampLabel.Text = {'SWR'; ''};
            app.SWRLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end